classdef Smart_Fitness_Tracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DistanceLabel                 matlab.ui.control.Label
        DistanceEditField             matlab.ui.control.EditField
        DistanceEditFieldLabel        matlab.ui.control.Label
        FlightsClimbedEditField       matlab.ui.control.EditField
        FlightsClimbedEditFieldLabel  matlab.ui.control.Label
        CaloriesBurnedEditField       matlab.ui.control.EditField
        CaloriesBurnedEditFieldLabel  matlab.ui.control.Label
        TotalStepsEditField           matlab.ui.control.EditField
        TotalStepsEditFieldLabel      matlab.ui.control.Label
        DurationEditField             matlab.ui.control.EditField
        DurationEditFieldLabel        matlab.ui.control.Label
        ActivityTypeEditField         matlab.ui.control.EditField
        ActivityTypeLabel_2           matlab.ui.control.Label
        TotalStepsLabel               matlab.ui.control.Label
        CaloriesBurnedLabel           matlab.ui.control.Label
        FlightsClimbedLabel           matlab.ui.control.Label
        DurationLabel                 matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        ActivityTypeLabel             matlab.ui.control.Label
        LoadAnalyzeWorkoutButton      matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadAnalyzeWorkoutButton
        function LoadAnalyzeWorkoutButtonPushed(app, event)
          

% 1. Open a file dialog to select a .mat file
[file, path] = uigetfile('*.mat', 'Select a Workout Data File');
if isequal(file, 0)
    % User cancelled the selection
    return;
end
full_filename = fullfile(path, file);
load(full_filename);

% Make sure the data is valid
if exist('Acceleration', 'var') && exist('Position', 'var') && ~isempty(Acceleration) && ~isempty(Position)
    
    % Update a label to show the app is working
    app.ActivityTypeLabel.Text = 'Activity Type: Analyzing...';
    drawnow; % Force the UI to update

    % 2. RUN THE ENTIRE BACKEND ENGINE
    % Detect Activity
    [avg_mag, shakiness] = analyzeActivity(Acceleration);
    if contains(file, 'Stairs', 'IgnoreCase', true)
        detected_mode = 'stairs';
    elseif shakiness > 2.0 
        detected_mode = 'running';
    elseif avg_mag < 1.0 && shakiness < 0.5 
        detected_mode = 'stabilized';
    else
        detected_mode = 'walking';
    end
    
    % Run All Calculations
    user_weight_kg = 70; % Example weight
    session_duration = calculateDuration(Acceleration);
    [step_count, height, prom] = Steps_Count(Acceleration, detected_mode);
    total_distance = calculateDistance(Position);
    flights_climbed = countStairs(Position);
    [calories_burned, ~] = calculateCalories(detected_mode, session_duration, user_weight_kg);

    % 3. UPDATE THE APP DISPLAY
    % Update the text fields
    app.ActivityTypeEditField.Value = detected_mode;
    
    mins = floor(session_duration / 60);
    secs = rem(session_duration, 60);
    app.DurationEditField.Value = sprintf('%d min, %.0f sec', mins, secs);
    
    app.TotalStepsEditField.Value = num2str(step_count);
    app.DistanceEditField.Value = sprintf('%.1f meters', total_distance);
    app.FlightsClimbedEditField.Value = num2str(flights_climbed);
    app.CaloriesBurnedEditField.Value = sprintf('%.1f kcal', calories_burned);
    
    % Update the plots by calling your modified functions
    plotRouteMap(app.UIAxes, Position, total_distance);
    visualiseSteps(app.UIAxes2, Acceleration, height, prom);

else
    app.ActivityTypeLabel.Text = 'Activity Type: Invalid File';
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.LoadAnalyzeWorkoutButton = uibutton(app.UIFigure, 'push');
            app.LoadAnalyzeWorkoutButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAnalyzeWorkoutButtonPushed, true);
            app.LoadAnalyzeWorkoutButton.Text = 'Load & Analyze Workout';
            app.ActivityTypeLabel = uilabel(app.UIFigure);
            app.ActivityTypeLabel.Text = 'Activity Type';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.DurationLabel = uilabel(app.UIFigure);
            app.DurationLabel.Text = 'Duration';
            app.FlightsClimbedLabel = uilabel(app.UIFigure);
            app.FlightsClimbedLabel.Text = 'Flights Climbed';
            app.CaloriesBurnedLabel = uilabel(app.UIFigure);
            app.CaloriesBurnedLabel.Text = 'Calories Burned';
            app.TotalStepsLabel = uilabel(app.UIFigure);
            app.TotalStepsLabel.Text = 'Total Steps';
            app.ActivityTypeLabel_2 = uilabel(app.UIFigure);
            app.ActivityTypeLabel_2.Text = 'ActivityType';
            app.ActivityTypeEditField = uieditfield(app.UIFigure, 'text');
            app.DurationEditFieldLabel = uilabel(app.UIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.UIFigure, 'text');
            app.TotalStepsEditFieldLabel = uilabel(app.UIFigure);
            app.TotalStepsEditFieldLabel.Text = 'TotalSteps';
            app.TotalStepsEditField = uieditfield(app.UIFigure, 'text');
            app.CaloriesBurnedEditFieldLabel = uilabel(app.UIFigure);
            app.CaloriesBurnedEditFieldLabel.Text = 'CaloriesBurned';
            app.CaloriesBurnedEditField = uieditfield(app.UIFigure, 'text');
            app.FlightsClimbedEditFieldLabel = uilabel(app.UIFigure);
            app.FlightsClimbedEditFieldLabel.Text = 'FlightsClimbed';
            app.FlightsClimbedEditField = uieditfield(app.UIFigure, 'text');
            app.DistanceEditFieldLabel = uilabel(app.UIFigure);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.UIFigure, 'text');
            app.DistanceLabel = uilabel(app.UIFigure);
            app.DistanceLabel.Text = 'Distance';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Smart_Fitness_Tracker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end